classdef CustomerUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Shower          matlab.ui.container.Menu
        Menu1_1         matlab.ui.container.Menu
        Menu2_1         matlab.ui.container.Menu
        Menu3_1         matlab.ui.container.Menu
        Menu4_1         matlab.ui.container.Menu
        Menu5_1         matlab.ui.container.Menu
        Menu6_1         matlab.ui.container.Menu
        Menu7_1         matlab.ui.container.Menu
        Menu8_1         matlab.ui.container.Menu
        Food            matlab.ui.container.Menu
        Menu1_2         matlab.ui.container.Menu
        Menu2_2         matlab.ui.container.Menu
        Menu3_2         matlab.ui.container.Menu
        Menu4_2         matlab.ui.container.Menu
        Menu5_2         matlab.ui.container.Menu
        Menu6_2         matlab.ui.container.Menu
        Menu7_2         matlab.ui.container.Menu
        Menu8_2         matlab.ui.container.Menu
        Water           matlab.ui.container.Menu
        Menu1_3         matlab.ui.container.Menu
        Menu2_3         matlab.ui.container.Menu
        Menu3_3         matlab.ui.container.Menu
        Menu4_3         matlab.ui.container.Menu
        Menu5_3         matlab.ui.container.Menu
        Menu6_3         matlab.ui.container.Menu
        Menu7_3         matlab.ui.container.Menu
        Menu8_3         matlab.ui.container.Menu
        Drink           matlab.ui.container.Menu
        Menu1_4         matlab.ui.container.Menu
        Menu2_4         matlab.ui.container.Menu
        Menu3_4         matlab.ui.container.Menu
        Menu4_4         matlab.ui.container.Menu
        Menu5_4         matlab.ui.container.Menu
        Menu6_4         matlab.ui.container.Menu
        Menu7_4         matlab.ui.container.Menu
        Menu8_4         matlab.ui.container.Menu
        Tools           matlab.ui.container.Menu
        Menu1_5         matlab.ui.container.Menu
        Menu2_5         matlab.ui.container.Menu
        Menu3_5         matlab.ui.container.Menu
        Menu4_5         matlab.ui.container.Menu
        Menu5_5         matlab.ui.container.Menu
        Menu6_5         matlab.ui.container.Menu
        Menu7_5         matlab.ui.container.Menu
        Menu8_5         matlab.ui.container.Menu
        Cloth           matlab.ui.container.Menu
        Menu1_6         matlab.ui.container.Menu
        Menu2_6         matlab.ui.container.Menu
        Menu3_6         matlab.ui.container.Menu
        Menu4_6         matlab.ui.container.Menu
        Menu5_6         matlab.ui.container.Menu
        Menu6_6         matlab.ui.container.Menu
        Menu7_6         matlab.ui.container.Menu
        Menu8_6         matlab.ui.container.Menu
        Digits          matlab.ui.container.Menu
        Menu1_7         matlab.ui.container.Menu
        Menu2_7         matlab.ui.container.Menu
        Menu3_7         matlab.ui.container.Menu
        Menu4_7         matlab.ui.container.Menu
        Menu5_7         matlab.ui.container.Menu
        Menu6_7         matlab.ui.container.Menu
        Menu7_7         matlab.ui.container.Menu
        Menu8_7         matlab.ui.container.Menu
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        item1           matlab.ui.control.Button
        item2           matlab.ui.control.Button
        item1_plus      matlab.ui.control.Button
        item2_plus      matlab.ui.control.Button
        item1_minus     matlab.ui.control.Button
        item2_minus     matlab.ui.control.Button
        item3           matlab.ui.control.Button
        item4           matlab.ui.control.Button
        item5           matlab.ui.control.Button
        item6           matlab.ui.control.Button
        item7           matlab.ui.control.Button
        item8           matlab.ui.control.Button
        item9           matlab.ui.control.Button
        item10          matlab.ui.control.Button
        item3_plus      matlab.ui.control.Button
        item3_minus     matlab.ui.control.Button
        item4_plus      matlab.ui.control.Button
        item4_minus     matlab.ui.control.Button
        item5_plus      matlab.ui.control.Button
        item5_minus     matlab.ui.control.Button
        item6_plus      matlab.ui.control.Button
        item6_minus     matlab.ui.control.Button
        item7_plus      matlab.ui.control.Button
        item7_minus     matlab.ui.control.Button
        item8_plus      matlab.ui.control.Button
        item8_minus     matlab.ui.control.Button
        item9_plus      matlab.ui.control.Button
        item9_minus     matlab.ui.control.Button
        item10_plus     matlab.ui.control.Button
        item10_minus    matlab.ui.control.Button
        Syn             matlab.ui.control.Button
        CenterPanel     matlab.ui.container.Panel
        Image           matlab.ui.control.Image
        unitPriceLabel  matlab.ui.control.Label
        unitPrice       matlab.ui.control.NumericEditField
        PaySuccess      matlab.ui.control.Button
        unitNumLabel    matlab.ui.control.Label
        unitNum         matlab.ui.control.NumericEditField
        Printer         matlab.ui.control.Button
        UITable_2       matlab.ui.control.Table
        RightPanel      matlab.ui.container.Panel
        UITable         matlab.ui.control.Table
        Label_2         matlab.ui.control.Label
        TotalPrice      matlab.ui.control.NumericEditField
        PayButton       matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        item_nums = 0;
        item_name = [""];
        item_number = [];
        item_price = [];
        
        order_name = [""];
        order_number = [];
        order_price = [];
        
        max_items_per_type = 10;
        
        cashier = Cashier();
        db = Database();
    end
    
    methods (Access = private)
        
        function change_order_add(app, item)
            % find goods' price 
            
            goods = app.db.findGoods(item.Text);
            if strcmp(goods.brand, item.Text)
                app.Image.ImageSource = goods.img_src;
                app.unitPrice.Value = goods.price;
                app.unitNum.Value = goods.getNumber();
                if app.unitNum.Value == 0
                    %app.unitPriceLabel.Visible = 'off';
                    %app.unitPrice.Visible = 'off';
                    %app.unitNumLabel.Visible = 'off';
                    %app.unitNum.Visible = 'off';
                    
                    app.PaySuccess.Text = '缺货！！！';
                    app.PaySuccess.Visible = 'on';
                    % app.PayButton.Enable = false;
                    
                    t = timer;
                    t.StartDelay = 1;
                    t.TimerFcn = {@(~,~)app.payTimerCallBack};
                    start(t)
                    return;
                else
                    app.unitNum.Value = app.unitNum.Value - 1;
                end
            

                app.db.setNumber(goods, app.unitNum.Value);
            end
            
            
            is_find = false;
            %app.UITable.RowName = {'1';};
            % check if item is in the order list
            for i = 1:app.item_nums
                % find the same item in the order, increase number and
                % price
                if strcmp(app.item_name(i,1), item.Text)
                    app.item_number(i,1) = app.item_number(i) + 1;
                    
                    app.item_price(i,1) = app.item_price(i) + app.unitPrice.Value;
                    is_find = true;
                end
            end
            
            if is_find == false
                %app.UITable.RowName{app.item_nums} = app.item_nums;
                if app.item_nums == 0
                    app.item_name = [""];
                end
                app.item_nums = app.item_nums + 1;
                app.item_name(app.item_nums,1) = item.Text;
                app.item_number(app.item_nums,1) = 1;
                app.item_price(app.item_nums,1) = app.unitPrice.Value;
                
            end
            % change total price
            total_price = sum(app.item_price);
            app.TotalPrice.Value = total_price;
            
            tdata = table(app.item_name, app.item_number, app.item_price);
            app.UITable.Data = tdata;
        end
        
        
        function change_order_sub(app,item)
            is_find = false;
            %app.UITable.RowName = {'1';};
            % check if item is in the order list
            goods = app.db.findGoods(item.Text);
            
            for i = 1:app.item_nums
                % find the same item in the order, increase number and
                % price
                if strcmp(app.item_name(i,1), item.Text)
                    
                    
                    app.item_number(i,1) = app.item_number(i) - 1;
                    app.item_price(i,1) = app.item_price(i) - goods.getPrice();
                    
                    goods.setNumber(goods.getNumber() + 1);

                    is_find = true;
                    
                    % show info
                    app.Image.ImageSource = goods.img_src;
                    app.unitPrice.Value = goods.price;
                    app.unitNum.Value = goods.getNumber();
                    break;
                end
            end
            if is_find == true && app.item_number(i,1) == 0
                
                %app.UITable.RowName{i,:} = [];
                app.item_name(i,:) = [];
                app.item_number(i,:) = [];
                app.item_price(i,:) = [];
                app.item_nums = app.item_nums - 1;
            end
            % change total price
            total_price = sum(app.item_price);
            app.TotalPrice.Value = total_price;
            
            tdata = table(app.item_name, app.item_number, app.item_price);
            app.UITable.Data = tdata;
        end
        
        function itemButtonPushed(app, item)
            for i = 1:app.db.getGoodsNum()
                goods = app.db.getGoods(i);
                if strcmp(goods.brand, item.Text)
                    eval("app.Image.ImageSource = goods.img_src;");
                    eval("app.unitPrice.Value = goods.getPrice();");
                    eval("app.unitNum.Value = goods.getNumber();");
                end
            end
            
            app.unitPriceLabel.Visible = 'on';
            app.unitPrice.Visible = 'on';
            app.unitNumLabel.Visible = 'on';
            app.unitNum.Visible = 'on';
            app.Image.Visible = 'on';
        end
        
        function menuSelected(app, type_name)
            count = 1;
            for i = 1:app.db.getGoodsNum()
                goods = app.db.getGoods(i);
                if strcmp(goods.type, type_name)
                    eval("app.item"+num2str(count)+".Text = goods.getBrand(); ");
                    eval("app.item"+num2str(count)+".Visible = 'on'; ");
                    eval("app.item"+num2str(count)+"_plus.Visible = 'on'; ");
                    eval("app.item"+num2str(count)+"_minus.Visible = 'on'; ");
                    count = count + 1;
                end
            end
            for j = count : app.max_items_per_type
                eval("app.item"+num2str(j)+".Visible = 'off'; ");
                eval("app.item"+num2str(j)+"_plus.Visible = 'off'; ");
                eval("app.item"+num2str(j)+"_minus.Visible = 'off'; ");
            end
        end
        
        
        function startup(app)
            for i = 1:app.db.getGoodsNum()
                goods = app.db.getGoods(i);

                affiliate = goods.affiliate;
                if strcmp(affiliate, app.Shower.Text)
                    app.showMenu(goods, 1);
                elseif strcmp(affiliate, app.Food.Text)
                    app.showMenu(goods, 2);
                elseif strcmp(affiliate, app.Water.Text)
                    app.showMenu(goods, 3);
                elseif strcmp(affiliate, app.Drink.Text)
                    app.showMenu(goods, 4);
                elseif strcmp(affiliate, app.Tools.Text)
                    app.showMenu(goods, 5);
                elseif strcmp(affiliate, app.Cloth.Text)
                    app.showMenu(goods, 6);
                else
                    app.showMenu(goods, 7);
                end
            end
        end
        
        function showMenu(app, goods, menu_num)
            for j = 1:8  % find the Menu which visible is off
                is_visible = eval("app.Menu"+num2str(j)+"_"+num2str(menu_num)+".Visible");
                name = eval("app.Menu"+num2str(j)+"_"+num2str(menu_num)+".Text");
                if strcmp(goods.getType(), name)
                    break;
                end
                if strcmp(is_visible, "off")
                    eval("app.Menu"+num2str(j)+"_"+num2str(menu_num)+".Visible = 'on'");
                    eval("app.Menu"+num2str(j)+"_"+num2str(menu_num)+".Text = goods.type");
                    break;
                end
            end
        end
        
        function printerTimerCallBack(app)
            eval("app.UITable_2.Visible = 'off';");
        end
        
        function payTimerCallBack(app)
            eval("app.PaySuccess.Visible = 'off';");
        end
        
    end
    
    methods (Access = public)
        
        function db = getDatabase(app)
            db = app.db;
        end
        
        function setDatabase(app,db)
            app.db = db;
        end
        
        function Ticket = getTicket(app)
            Ticket = app.UITable_2.Data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.startup();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {207, '1x', 302};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Menu selected function: Menu1_1
        function Menu1_1Selected(app, event)
            app.menuSelected(app.Menu1_1.Text);
            
        end

        % Button pushed function: item1
        function item1ButtonPushed(app, event)
            app.itemButtonPushed(app.item1);
        end

        % Button pushed function: item2
        function item2ButtonPushed(app, event)
            app.itemButtonPushed(app.item2);
        end

        % Menu selected function: Menu2_1
        function Menu2_1Selected(app, event)
            app.menuSelected(app.Menu2_1.Text);
        end

        % Button pushed function: item1_plus
        function item1_plusButtonPushed(app, event)
            app.change_order_add(app.item1);
        end

        % Button pushed function: item2_plus
        function item2_plusButtonPushed(app, event)
            app.change_order_add(app.item2);
        end

        % Button pushed function: item1_minus
        function item1_minusButtonPushed(app, event)
            app.change_order_sub(app.item1);
        end

        % Button pushed function: item2_minus
        function item2_minusButtonPushed(app, event)
            app.change_order_sub(app.item2);
        end

        % Button pushed function: PayButton
        function PayButtonPushed(app, event)
            % create order and write it into database once we finish paying
            % all goods are in database, what we have to do is to find the
            % goods obj in the database
            app.cashier.create_order();
            for i = 1:app.item_nums
                name = app.item_name(i, 1);
                num = app.item_number(i, 1);
                goods = app.db.findGoods(name);
                if goods.brand == name
                    for j = 1:num
                        app.cashier.addItem(goods);
                    end
                end
            end
            app.cashier.writeOrderBackToDatabase();
            
            app.db.writeData();
            
            app.order_name = app.item_name;
            app.order_number = app.item_number;
            app.order_price = app.item_price;
        
            app.item_name = [];
            app.item_number = [];
            app.item_price = [];
            app.item_nums = 0;
            
            % change total price
            total_price = 0;
            app.TotalPrice.Value = total_price;
            
            tdata = table(app.item_name, app.item_number, app.item_price);
            app.UITable.Data = tdata;
            app.PaySuccess.Text = '支付成功！点击继续下次购物';
            app.PaySuccess.Visible = 'on';
            app.PayButton.Enable = false;


            app.Printer.Visible = 'on';
            
            app.unitPriceLabel.Visible = 'off';
            app.unitPrice.Visible = 'off';
            app.unitNumLabel.Visible = 'off';
            app.unitNum.Visible = 'off';
            
        end

        % Button pushed function: PaySuccess
        function PaySuccessButtonPushed(app, event)
            app.PaySuccess.Visible = 'off';
            app.PayButton.Enable = true;
            
            app.unitPriceLabel.Visible = 'on';
            app.unitPrice.Visible = 'on';
            app.unitNumLabel.Visible = 'on';
            app.unitNum.Visible = 'on';
            
            app.Printer.Visible = 'on';
        end

        % Menu selected function: Shower
        function ShowerSelected(app, event)
            %add path once we select the menu


        end

        % Button pushed function: item3
        function item3ButtonPushed(app, event)
            app.itemButtonPushed(app.item3);
        end

        % Button pushed function: item4
        function item4ButtonPushed(app, event)
            app.itemButtonPushed(app.item4);
        end

        % Button pushed function: item5
        function item5ButtonPushed(app, event)
            app.itemButtonPushed(app.item5);
        end

        % Button pushed function: item6
        function item6ButtonPushed(app, event)
            app.itemButtonPushed(app.item6);
        end

        % Button pushed function: item7
        function item7ButtonPushed(app, event)
            app.itemButtonPushed(app.item7);
        end

        % Button pushed function: item8
        function item8ButtonPushed(app, event)
            app.itemButtonPushed(app.item8);
        end

        % Button pushed function: item9
        function item9ButtonPushed(app, event)
            app.itemButtonPushed(app.item9);
        end

        % Button pushed function: item10
        function item10ButtonPushed(app, event)
            app.itemButtonPushed(app.item10);
        end

        % Button pushed function: item3_plus
        function item3_plusButtonPushed(app, event)
            app.change_order_add(app.item3);
        end

        % Button pushed function: item3_minus
        function item3_minusButtonPushed(app, event)
            app.change_order_sub(app.item3);
        end

        % Button pushed function: item4_plus
        function item4_plusButtonPushed(app, event)
            app.change_order_add(app.item4);
        end

        % Button pushed function: item4_minus
        function item4_minusButtonPushed(app, event)
            app.change_order_sub(app.item4);
        end

        % Button pushed function: item5_plus
        function item5_plusButtonPushed(app, event)
            app.change_order_add(app.item5);
        end

        % Button pushed function: item5_minus
        function item5_minusButtonPushed(app, event)
            app.change_order_sub(app.item5);
        end

        % Button pushed function: item6_plus
        function item6_plusButtonPushed(app, event)
            app.change_order_add(app.item6);
        end

        % Button pushed function: item6_minus
        function item6_minusButtonPushed(app, event)
            app.change_order_sub(app.item6);
        end

        % Button pushed function: item7_plus
        function item7_plusButtonPushed(app, event)
            app.change_order_add(app.item7);
        end

        % Button pushed function: item7_minus
        function item7_minusButtonPushed(app, event)
            app.change_order_sub(app.item7);
        end

        % Button pushed function: item8_plus
        function item8_plusButtonPushed(app, event)
            app.change_order_add(app.item8);
        end

        % Button pushed function: item8_minus
        function item8_minusButtonPushed(app, event)
            app.change_order_sub(app.item8);
        end

        % Button pushed function: item9_plus
        function item9_plusButtonPushed(app, event)
            app.change_order_add(app.item9);
        end

        % Button pushed function: item9_minus
        function item9_minusButtonPushed(app, event)
            app.change_order_sub(app.item9);
        end

        % Button pushed function: item10_plus
        function item10_plusButtonPushed(app, event)
            app.change_order_add(app.item10);
        end

        % Button pushed function: item10_minus
        function item10_minusButtonPushed(app, event)
            app.change_order_sub(app.item10);
        end

        % Menu selected function: Menu1_2
        function Menu1_2Selected(app, event)
            app.menuSelected(app.Menu1_2.Text);
        end

        % Menu selected function: Menu2_2
        function Menu2_2Selected(app, event)
            app.menuSelected(app.Menu2_2.Text);
        end

        % Menu selected function: Menu3_1
        function Menu3_1Selected(app, event)
            app.menuSelected(app.Menu3_1.Text);
        end

        % Menu selected function: Menu4_1
        function Menu4_1Selected(app, event)
            app.menuSelected(app.Menu4_1.Text);
        end

        % Menu selected function: Menu5_1
        function Menu5_1Selected(app, event)
            app.menuSelected(app.Menu5_1.Text);
        end

        % Menu selected function: Menu6_1
        function Menu6_1Selected(app, event)
            app.menuSelected(app.Menu6_1.Text);
        end

        % Menu selected function: Menu7_1
        function Menu7_1Selected(app, event)
            app.menuSelected(app.Menu7_1.Text);
        end

        % Menu selected function: Menu8_1
        function Menu8_1Selected(app, event)
            app.menuSelected(app.Menu8_1.Text);
        end

        % Menu selected function: Menu3_2
        function Menu3_2Selected(app, event)
            app.menuSelected(app.Menu3_2.Text);
        end

        % Menu selected function: Menu4_2
        function Menu4_2Selected(app, event)
            app.menuSelected(app.Menu4_2.Text);
        end

        % Menu selected function: Menu5_2
        function Menu5_2Selected(app, event)
            app.menuSelected(app.Menu5_2.Text);
        end

        % Menu selected function: Menu6_2
        function Menu6_2Selected(app, event)
            app.menuSelected(app.Menu6_2.Text);
        end

        % Menu selected function: Menu7_2
        function Menu7_2Selected(app, event)
            app.menuSelected(app.Menu7_2.Text);
        end

        % Menu selected function: Menu8_2
        function Menu8_2Selected(app, event)
            app.menuSelected(app.Menu8_2.Text);
        end

        % Menu selected function: Menu1_3
        function Menu1_3Selected(app, event)
            app.menuSelected(app.Menu1_3.Text);
        end

        % Menu selected function: Menu2_3
        function Menu2_3Selected(app, event)
            app.menuSelected(app.Menu2_3.Text);
        end

        % Menu selected function: Menu3_3
        function Menu3_3Selected(app, event)
            app.menuSelected(app.Menu3_3.Text);
        end

        % Menu selected function: Menu4_3
        function Menu4_3Selected(app, event)
            app.menuSelected(app.Menu4_3.Text);
        end

        % Menu selected function: Menu5_3
        function Menu5_3Selected(app, event)
            app.menuSelected(app.Menu5_3.Text);
        end

        % Menu selected function: Menu6_3
        function Menu6_3Selected(app, event)
            app.menuSelected(app.Menu6_3.Text);
        end

        % Menu selected function: Menu7_3
        function Menu7_3Selected(app, event)
            app.menuSelected(app.Menu7_3.Text);
        end

        % Menu selected function: Menu8_3
        function Menu8_3Selected(app, event)
            app.menuSelected(app.Menu8_3.Text);
        end

        % Menu selected function: Menu1_4
        function Menu1_4Selected(app, event)
            app.menuSelected(app.Menu1_4.Text);
        end

        % Menu selected function: Menu2_4
        function Menu2_4Selected(app, event)
            app.menuSelected(app.Menu2_4.Text);
        end

        % Menu selected function: Menu3_4
        function Menu3_4Selected(app, event)
            app.menuSelected(app.Menu3_4.Text);
        end

        % Menu selected function: Menu4_4
        function Menu4_4Selected(app, event)
            app.menuSelected(app.Menu4_4.Text);
        end

        % Menu selected function: Menu5_4
        function Menu5_4Selected(app, event)
            app.menuSelected(app.Menu5_4.Text);
        end

        % Menu selected function: Menu6_4
        function Menu6_4Selected(app, event)
            app.menuSelected(app.Menu6_4.Text);
        end

        % Menu selected function: Menu7_4
        function Menu7_4Selected(app, event)
            app.menuSelected(app.Menu7_4.Text);
        end

        % Menu selected function: Menu8_4
        function Menu8_4Selected(app, event)
            app.menuSelected(app.Menu8_4.Text);
        end

        % Menu selected function: Menu1_5
        function Menu1_5Selected(app, event)
            app.menuSelected(app.Menu1_5.Text);
        end

        % Menu selected function: Menu2_5
        function Menu2_5Selected(app, event)
            app.menuSelected(app.Menu5_5.Text);
        end

        % Menu selected function: Menu3_5
        function Menu3_5Selected(app, event)
            app.menuSelected(app.Menu3_5.Text);
        end

        % Menu selected function: Menu4_5
        function Menu4_5Selected(app, event)
            app.menuSelected(app.Menu4_5.Text);
        end

        % Menu selected function: Menu5_5
        function Menu5_5Selected(app, event)
            app.menuSelected(app.Menu5_5.Text);
        end

        % Menu selected function: Menu6_5
        function Menu6_5Selected(app, event)
            app.menuSelected(app.Menu6_5.Text);
        end

        % Menu selected function: Menu7_5
        function Menu7_5Selected(app, event)
            app.menuSelected(app.Menu7_5.Text);
        end

        % Menu selected function: Menu8_5
        function Menu8_5Selected(app, event)
            app.menuSelected(app.Menu8_5.Text);
        end

        % Menu selected function: Menu1_6
        function Menu1_6Selected(app, event)
            app.menuSelected(app.Menu1_6.Text);
        end

        % Menu selected function: Menu2_6
        function Menu2_6Selected(app, event)
            app.menuSelected(app.Menu2_6.Text);
        end

        % Menu selected function: Menu3_6
        function Menu3_6Selected(app, event)
            app.menuSelected(app.Menu3_6.Text);
        end

        % Menu selected function: Menu4_6
        function Menu4_6Selected(app, event)
            app.menuSelected(app.Menu4_6.Text);
        end

        % Menu selected function: Menu5_6
        function Menu5_6Selected(app, event)
            app.menuSelected(app.Menu5_6.Text);
        end

        % Menu selected function: Menu6_6
        function Menu6_6Selected(app, event)
            app.menuSelected(app.Menu6_6.Text);
        end

        % Menu selected function: Menu7_6
        function Menu7_6Selected(app, event)
            app.menuSelected(app.Menu7_6.Text);
        end

        % Menu selected function: Menu8_6
        function Menu8_6Selected(app, event)
            app.menuSelected(app.Menu8_6.Text);
        end

        % Menu selected function: Menu1_7
        function Menu1_7Selected(app, event)
            app.menuSelected(app.Menu1_7.Text);
        end

        % Menu selected function: Menu2_7
        function Menu2_7Selected(app, event)
            app.menuSelected(app.Menu2_7.Text);
        end

        % Menu selected function: Menu3_7
        function Menu3_7Selected(app, event)
            app.menuSelected(app.Menu3_7.Text);
        end

        % Menu selected function: Menu4_7
        function Menu4_7Selected(app, event)
            app.menuSelected(app.Menu4_7.Text);
        end

        % Menu selected function: Menu5_7
        function Menu5_7Selected(app, event)
            app.menuSelected(app.Menu5_7.Text);
        end

        % Menu selected function: Menu6_7
        function Menu6_7Selected(app, event)
            app.menuSelected(app.Menu6_7.Text);
        end

        % Menu selected function: Menu7_7
        function Menu7_7Selected(app, event)
            app.menuSelected(app.Menu7_7.Text);
        end

        % Menu selected function: Menu8_7
        function Menu8_7Selected(app, event)
            app.menuSelected(app.Menu8_7.Text);
        end

        % Button pushed function: Syn
        function SynButtonPushed(app, event)
            app.db = Database();
            app.startup();
        end

        % Button pushed function: Printer
        function PrinterButtonPushed(app, event)
            app.Printer.Visible = 'off';
            
            app.UITable_2.Visible = 'on';
            tdata = table(app.order_name, app.order_number, app.order_price);
            app.UITable_2.Data = tdata;
            app.UITable_2.Visible = 'off';
            t = timer;
            t.StartDelay = 3;
            t.TimerFcn = {@(~,~)app.printerTimerCallBack};
            start(t)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Shower = uimenu(app.UIFigure);
            app.Shower.MenuSelectedFcn = createCallbackFcn(app, @ShowerSelected, true);
            app.Shower.Text = '美妆洗护';
            app.Menu1_1 = uimenu(app.Shower);
            app.Menu1_1.MenuSelectedFcn = createCallbackFcn(app, @Menu1_1Selected, true);
            app.Menu1_1.Visible = 'off';
            app.Menu1_1.Text = '0';
            app.Menu2_1 = uimenu(app.Shower);
            app.Menu2_1.MenuSelectedFcn = createCallbackFcn(app, @Menu2_1Selected, true);
            app.Menu2_1.Visible = 'off';
            app.Menu2_1.Text = '0';
            app.Menu3_1 = uimenu(app.Shower);
            app.Menu3_1.MenuSelectedFcn = createCallbackFcn(app, @Menu3_1Selected, true);
            app.Menu3_1.Visible = 'off';
            app.Menu3_1.Text = 'Menu';
            app.Menu4_1 = uimenu(app.Shower);
            app.Menu4_1.MenuSelectedFcn = createCallbackFcn(app, @Menu4_1Selected, true);
            app.Menu4_1.Visible = 'off';
            app.Menu4_1.Text = 'Menu2';
            app.Menu5_1 = uimenu(app.Shower);
            app.Menu5_1.MenuSelectedFcn = createCallbackFcn(app, @Menu5_1Selected, true);
            app.Menu5_1.Visible = 'off';
            app.Menu5_1.Text = 'Menu3';
            app.Menu6_1 = uimenu(app.Shower);
            app.Menu6_1.MenuSelectedFcn = createCallbackFcn(app, @Menu6_1Selected, true);
            app.Menu6_1.Visible = 'off';
            app.Menu6_1.Text = 'Menu4';
            app.Menu7_1 = uimenu(app.Shower);
            app.Menu7_1.MenuSelectedFcn = createCallbackFcn(app, @Menu7_1Selected, true);
            app.Menu7_1.Visible = 'off';
            app.Menu7_1.Text = 'Menu5';
            app.Menu8_1 = uimenu(app.Shower);
            app.Menu8_1.MenuSelectedFcn = createCallbackFcn(app, @Menu8_1Selected, true);
            app.Menu8_1.Visible = 'off';
            app.Menu8_1.Text = 'Menu6';
            app.Food = uimenu(app.UIFigure);
            app.Food.Text = '食品';
            app.Menu1_2 = uimenu(app.Food);
            app.Menu1_2.MenuSelectedFcn = createCallbackFcn(app, @Menu1_2Selected, true);
            app.Menu1_2.Visible = 'off';
            app.Menu1_2.Text = '0';
            app.Menu2_2 = uimenu(app.Food);
            app.Menu2_2.MenuSelectedFcn = createCallbackFcn(app, @Menu2_2Selected, true);
            app.Menu2_2.Visible = 'off';
            app.Menu2_2.Text = '0';
            app.Menu3_2 = uimenu(app.Food);
            app.Menu3_2.MenuSelectedFcn = createCallbackFcn(app, @Menu3_2Selected, true);
            app.Menu3_2.Visible = 'off';
            app.Menu3_2.Text = 'Menu3';
            app.Menu4_2 = uimenu(app.Food);
            app.Menu4_2.MenuSelectedFcn = createCallbackFcn(app, @Menu4_2Selected, true);
            app.Menu4_2.Visible = 'off';
            app.Menu4_2.Text = 'Menu4';
            app.Menu5_2 = uimenu(app.Food);
            app.Menu5_2.MenuSelectedFcn = createCallbackFcn(app, @Menu5_2Selected, true);
            app.Menu5_2.Visible = 'off';
            app.Menu5_2.Text = 'Menu5';
            app.Menu6_2 = uimenu(app.Food);
            app.Menu6_2.MenuSelectedFcn = createCallbackFcn(app, @Menu6_2Selected, true);
            app.Menu6_2.Visible = 'off';
            app.Menu6_2.Text = 'Menu6';
            app.Menu7_2 = uimenu(app.Food);
            app.Menu7_2.MenuSelectedFcn = createCallbackFcn(app, @Menu7_2Selected, true);
            app.Menu7_2.Visible = 'off';
            app.Menu7_2.Text = 'Menu7';
            app.Menu8_2 = uimenu(app.Food);
            app.Menu8_2.MenuSelectedFcn = createCallbackFcn(app, @Menu8_2Selected, true);
            app.Menu8_2.Visible = 'off';
            app.Menu8_2.Text = 'Menu8';
            app.Water = uimenu(app.UIFigure);
            app.Water.Text = '饮料';
            app.Menu1_3 = uimenu(app.Water);
            app.Menu1_3.MenuSelectedFcn = createCallbackFcn(app, @Menu1_3Selected, true);
            app.Menu1_3.Visible = 'off';
            app.Menu1_3.Text = 'Menu';
            app.Menu2_3 = uimenu(app.Water);
            app.Menu2_3.MenuSelectedFcn = createCallbackFcn(app, @Menu2_3Selected, true);
            app.Menu2_3.Visible = 'off';
            app.Menu2_3.Text = 'Menu2';
            app.Menu3_3 = uimenu(app.Water);
            app.Menu3_3.MenuSelectedFcn = createCallbackFcn(app, @Menu3_3Selected, true);
            app.Menu3_3.Visible = 'off';
            app.Menu3_3.Text = 'Menu3';
            app.Menu4_3 = uimenu(app.Water);
            app.Menu4_3.MenuSelectedFcn = createCallbackFcn(app, @Menu4_3Selected, true);
            app.Menu4_3.Visible = 'off';
            app.Menu4_3.Text = 'Menu4';
            app.Menu5_3 = uimenu(app.Water);
            app.Menu5_3.MenuSelectedFcn = createCallbackFcn(app, @Menu5_3Selected, true);
            app.Menu5_3.Visible = 'off';
            app.Menu5_3.Text = 'Menu5';
            app.Menu6_3 = uimenu(app.Water);
            app.Menu6_3.MenuSelectedFcn = createCallbackFcn(app, @Menu6_3Selected, true);
            app.Menu6_3.Visible = 'off';
            app.Menu6_3.Text = 'Menu6';
            app.Menu7_3 = uimenu(app.Water);
            app.Menu7_3.MenuSelectedFcn = createCallbackFcn(app, @Menu7_3Selected, true);
            app.Menu7_3.Visible = 'off';
            app.Menu7_3.Text = 'Menu7';
            app.Menu8_3 = uimenu(app.Water);
            app.Menu8_3.MenuSelectedFcn = createCallbackFcn(app, @Menu8_3Selected, true);
            app.Menu8_3.Visible = 'off';
            app.Menu8_3.Text = 'Menu8';
            app.Drink = uimenu(app.UIFigure);
            app.Drink.Text = '中外名酒';
            app.Menu1_4 = uimenu(app.Drink);
            app.Menu1_4.MenuSelectedFcn = createCallbackFcn(app, @Menu1_4Selected, true);
            app.Menu1_4.Visible = 'off';
            app.Menu1_4.Text = 'Menu';
            app.Menu2_4 = uimenu(app.Drink);
            app.Menu2_4.MenuSelectedFcn = createCallbackFcn(app, @Menu2_4Selected, true);
            app.Menu2_4.Visible = 'off';
            app.Menu2_4.Text = 'Menu2';
            app.Menu3_4 = uimenu(app.Drink);
            app.Menu3_4.MenuSelectedFcn = createCallbackFcn(app, @Menu3_4Selected, true);
            app.Menu3_4.Visible = 'off';
            app.Menu3_4.Text = 'Menu3';
            app.Menu4_4 = uimenu(app.Drink);
            app.Menu4_4.MenuSelectedFcn = createCallbackFcn(app, @Menu4_4Selected, true);
            app.Menu4_4.Visible = 'off';
            app.Menu4_4.Text = 'Menu4';
            app.Menu5_4 = uimenu(app.Drink);
            app.Menu5_4.MenuSelectedFcn = createCallbackFcn(app, @Menu5_4Selected, true);
            app.Menu5_4.Visible = 'off';
            app.Menu5_4.Text = 'Menu5';
            app.Menu6_4 = uimenu(app.Drink);
            app.Menu6_4.MenuSelectedFcn = createCallbackFcn(app, @Menu6_4Selected, true);
            app.Menu6_4.Visible = 'off';
            app.Menu6_4.Text = 'Menu6';
            app.Menu7_4 = uimenu(app.Drink);
            app.Menu7_4.MenuSelectedFcn = createCallbackFcn(app, @Menu7_4Selected, true);
            app.Menu7_4.Visible = 'off';
            app.Menu7_4.Text = 'Menu7';
            app.Menu8_4 = uimenu(app.Drink);
            app.Menu8_4.MenuSelectedFcn = createCallbackFcn(app, @Menu8_4Selected, true);
            app.Menu8_4.Visible = 'off';
            app.Menu8_4.Text = 'Menu8';
            app.Tools = uimenu(app.UIFigure);
            app.Tools.Text = '文具体育';
            app.Menu1_5 = uimenu(app.Tools);
            app.Menu1_5.MenuSelectedFcn = createCallbackFcn(app, @Menu1_5Selected, true);
            app.Menu1_5.Visible = 'off';
            app.Menu1_5.Text = 'Menu';
            app.Menu2_5 = uimenu(app.Tools);
            app.Menu2_5.MenuSelectedFcn = createCallbackFcn(app, @Menu2_5Selected, true);
            app.Menu2_5.Visible = 'off';
            app.Menu2_5.Text = 'Menu2';
            app.Menu3_5 = uimenu(app.Tools);
            app.Menu3_5.MenuSelectedFcn = createCallbackFcn(app, @Menu3_5Selected, true);
            app.Menu3_5.Visible = 'off';
            app.Menu3_5.Text = 'Menu3';
            app.Menu4_5 = uimenu(app.Tools);
            app.Menu4_5.MenuSelectedFcn = createCallbackFcn(app, @Menu4_5Selected, true);
            app.Menu4_5.Visible = 'off';
            app.Menu4_5.Text = 'Menu4';
            app.Menu5_5 = uimenu(app.Tools);
            app.Menu5_5.MenuSelectedFcn = createCallbackFcn(app, @Menu5_5Selected, true);
            app.Menu5_5.Visible = 'off';
            app.Menu5_5.Text = 'Menu5';
            app.Menu6_5 = uimenu(app.Tools);
            app.Menu6_5.MenuSelectedFcn = createCallbackFcn(app, @Menu6_5Selected, true);
            app.Menu6_5.Visible = 'off';
            app.Menu6_5.Text = 'Menu6';
            app.Menu7_5 = uimenu(app.Tools);
            app.Menu7_5.MenuSelectedFcn = createCallbackFcn(app, @Menu7_5Selected, true);
            app.Menu7_5.Visible = 'off';
            app.Menu7_5.Text = 'Menu7';
            app.Menu8_5 = uimenu(app.Tools);
            app.Menu8_5.MenuSelectedFcn = createCallbackFcn(app, @Menu8_5Selected, true);
            app.Menu8_5.Visible = 'off';
            app.Menu8_5.Text = 'Menu8';
            app.Cloth = uimenu(app.UIFigure);
            app.Cloth.Text = '家纺内衣';
            app.Menu1_6 = uimenu(app.Cloth);
            app.Menu1_6.MenuSelectedFcn = createCallbackFcn(app, @Menu1_6Selected, true);
            app.Menu1_6.Visible = 'off';
            app.Menu1_6.Text = 'Menu';
            app.Menu2_6 = uimenu(app.Cloth);
            app.Menu2_6.MenuSelectedFcn = createCallbackFcn(app, @Menu2_6Selected, true);
            app.Menu2_6.Visible = 'off';
            app.Menu2_6.Text = 'Menu2';
            app.Menu3_6 = uimenu(app.Cloth);
            app.Menu3_6.MenuSelectedFcn = createCallbackFcn(app, @Menu3_6Selected, true);
            app.Menu3_6.Visible = 'off';
            app.Menu3_6.Text = 'Menu3';
            app.Menu4_6 = uimenu(app.Cloth);
            app.Menu4_6.MenuSelectedFcn = createCallbackFcn(app, @Menu4_6Selected, true);
            app.Menu4_6.Visible = 'off';
            app.Menu4_6.Text = 'Menu4';
            app.Menu5_6 = uimenu(app.Cloth);
            app.Menu5_6.MenuSelectedFcn = createCallbackFcn(app, @Menu5_6Selected, true);
            app.Menu5_6.Visible = 'off';
            app.Menu5_6.Text = 'Menu5';
            app.Menu6_6 = uimenu(app.Cloth);
            app.Menu6_6.MenuSelectedFcn = createCallbackFcn(app, @Menu6_6Selected, true);
            app.Menu6_6.Visible = 'off';
            app.Menu6_6.Text = 'Menu6';
            app.Menu7_6 = uimenu(app.Cloth);
            app.Menu7_6.MenuSelectedFcn = createCallbackFcn(app, @Menu7_6Selected, true);
            app.Menu7_6.Visible = 'off';
            app.Menu7_6.Text = 'Menu7';
            app.Menu8_6 = uimenu(app.Cloth);
            app.Menu8_6.MenuSelectedFcn = createCallbackFcn(app, @Menu8_6Selected, true);
            app.Menu8_6.Visible = 'off';
            app.Menu8_6.Text = 'Menu8';
            app.Digits = uimenu(app.UIFigure);
            app.Digits.Text = '电器数码';
            app.Menu1_7 = uimenu(app.Digits);
            app.Menu1_7.MenuSelectedFcn = createCallbackFcn(app, @Menu1_7Selected, true);
            app.Menu1_7.Visible = 'off';
            app.Menu1_7.Text = 'Menu';
            app.Menu2_7 = uimenu(app.Digits);
            app.Menu2_7.MenuSelectedFcn = createCallbackFcn(app, @Menu2_7Selected, true);
            app.Menu2_7.Visible = 'off';
            app.Menu2_7.Text = 'Menu2';
            app.Menu3_7 = uimenu(app.Digits);
            app.Menu3_7.MenuSelectedFcn = createCallbackFcn(app, @Menu3_7Selected, true);
            app.Menu3_7.Visible = 'off';
            app.Menu3_7.Text = 'Menu3';
            app.Menu4_7 = uimenu(app.Digits);
            app.Menu4_7.MenuSelectedFcn = createCallbackFcn(app, @Menu4_7Selected, true);
            app.Menu4_7.Visible = 'off';
            app.Menu4_7.Text = 'Menu4';
            app.Menu5_7 = uimenu(app.Digits);
            app.Menu5_7.MenuSelectedFcn = createCallbackFcn(app, @Menu5_7Selected, true);
            app.Menu5_7.Visible = 'off';
            app.Menu5_7.Text = 'Menu5';
            app.Menu6_7 = uimenu(app.Digits);
            app.Menu6_7.MenuSelectedFcn = createCallbackFcn(app, @Menu6_7Selected, true);
            app.Menu6_7.Visible = 'off';
            app.Menu6_7.Text = 'Menu6';
            app.Menu7_7 = uimenu(app.Digits);
            app.Menu7_7.MenuSelectedFcn = createCallbackFcn(app, @Menu7_7Selected, true);
            app.Menu7_7.Visible = 'off';
            app.Menu7_7.Text = 'Menu7';
            app.Menu8_7 = uimenu(app.Digits);
            app.Menu8_7.MenuSelectedFcn = createCallbackFcn(app, @Menu8_7Selected, true);
            app.Menu8_7.Visible = 'off';
            app.Menu8_7.Text = 'Menu8';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.item1 = uibutton(app.LeftPanel, 'push');
            app.item1.ButtonPushedFcn = createCallbackFcn(app, @item1ButtonPushed, true);
            app.item1.Visible = 'off';
            app.item1.Text = '';
            app.item2 = uibutton(app.LeftPanel, 'push');
            app.item2.ButtonPushedFcn = createCallbackFcn(app, @item2ButtonPushed, true);
            app.item2.Visible = 'off';
            app.item2.Text = '';
            app.item1_plus = uibutton(app.LeftPanel, 'push');
            app.item1_plus.ButtonPushedFcn = createCallbackFcn(app, @item1_plusButtonPushed, true);
            app.item1_plus.Visible = 'off';
            app.item1_plus.Text = {'+'; ''};
            app.item2_plus = uibutton(app.LeftPanel, 'push');
            app.item2_plus.ButtonPushedFcn = createCallbackFcn(app, @item2_plusButtonPushed, true);
            app.item2_plus.Visible = 'off';
            app.item2_plus.Text = {'+'; ''};
            app.item1_minus = uibutton(app.LeftPanel, 'push');
            app.item1_minus.ButtonPushedFcn = createCallbackFcn(app, @item1_minusButtonPushed, true);
            app.item1_minus.Visible = 'off';
            app.item1_minus.Text = '-';
            app.item2_minus = uibutton(app.LeftPanel, 'push');
            app.item2_minus.ButtonPushedFcn = createCallbackFcn(app, @item2_minusButtonPushed, true);
            app.item2_minus.Visible = 'off';
            app.item2_minus.Text = '-';
            app.item3 = uibutton(app.LeftPanel, 'push');
            app.item3.ButtonPushedFcn = createCallbackFcn(app, @item3ButtonPushed, true);
            app.item3.Visible = 'off';
            app.item3.Text = '';
            app.item4 = uibutton(app.LeftPanel, 'push');
            app.item4.ButtonPushedFcn = createCallbackFcn(app, @item4ButtonPushed, true);
            app.item4.Visible = 'off';
            app.item4.Text = '';
            app.item5 = uibutton(app.LeftPanel, 'push');
            app.item5.ButtonPushedFcn = createCallbackFcn(app, @item5ButtonPushed, true);
            app.item5.Visible = 'off';
            app.item5.Text = '';
            app.item6 = uibutton(app.LeftPanel, 'push');
            app.item6.ButtonPushedFcn = createCallbackFcn(app, @item6ButtonPushed, true);
            app.item6.Visible = 'off';
            app.item6.Text = '';
            app.item7 = uibutton(app.LeftPanel, 'push');
            app.item7.ButtonPushedFcn = createCallbackFcn(app, @item7ButtonPushed, true);
            app.item7.Visible = 'off';
            app.item7.Text = '';
            app.item8 = uibutton(app.LeftPanel, 'push');
            app.item8.ButtonPushedFcn = createCallbackFcn(app, @item8ButtonPushed, true);
            app.item8.Visible = 'off';
            app.item8.Text = '';
            app.item9 = uibutton(app.LeftPanel, 'push');
            app.item9.ButtonPushedFcn = createCallbackFcn(app, @item9ButtonPushed, true);
            app.item9.Visible = 'off';
            app.item9.Text = '';
            app.item10 = uibutton(app.LeftPanel, 'push');
            app.item10.ButtonPushedFcn = createCallbackFcn(app, @item10ButtonPushed, true);
            app.item10.Visible = 'off';
            app.item10.Text = '';
            app.item3_plus = uibutton(app.LeftPanel, 'push');
            app.item3_plus.ButtonPushedFcn = createCallbackFcn(app, @item3_plusButtonPushed, true);
            app.item3_plus.Visible = 'off';
            app.item3_plus.Text = {'+'; ''};
            app.item3_minus = uibutton(app.LeftPanel, 'push');
            app.item3_minus.ButtonPushedFcn = createCallbackFcn(app, @item3_minusButtonPushed, true);
            app.item3_minus.Visible = 'off';
            app.item3_minus.Text = '-';
            app.item4_plus = uibutton(app.LeftPanel, 'push');
            app.item4_plus.ButtonPushedFcn = createCallbackFcn(app, @item4_plusButtonPushed, true);
            app.item4_plus.Visible = 'off';
            app.item4_plus.Text = {'+'; ''};
            app.item4_minus = uibutton(app.LeftPanel, 'push');
            app.item4_minus.ButtonPushedFcn = createCallbackFcn(app, @item4_minusButtonPushed, true);
            app.item4_minus.Visible = 'off';
            app.item4_minus.Text = '-';
            app.item5_plus = uibutton(app.LeftPanel, 'push');
            app.item5_plus.ButtonPushedFcn = createCallbackFcn(app, @item5_plusButtonPushed, true);
            app.item5_plus.Visible = 'off';
            app.item5_plus.Text = {'+'; ''};
            app.item5_minus = uibutton(app.LeftPanel, 'push');
            app.item5_minus.ButtonPushedFcn = createCallbackFcn(app, @item5_minusButtonPushed, true);
            app.item5_minus.Visible = 'off';
            app.item5_minus.Text = '-';
            app.item6_plus = uibutton(app.LeftPanel, 'push');
            app.item6_plus.ButtonPushedFcn = createCallbackFcn(app, @item6_plusButtonPushed, true);
            app.item6_plus.Visible = 'off';
            app.item6_plus.Text = {'+'; ''};
            app.item6_minus = uibutton(app.LeftPanel, 'push');
            app.item6_minus.ButtonPushedFcn = createCallbackFcn(app, @item6_minusButtonPushed, true);
            app.item6_minus.Visible = 'off';
            app.item6_minus.Text = '-';
            app.item7_plus = uibutton(app.LeftPanel, 'push');
            app.item7_plus.ButtonPushedFcn = createCallbackFcn(app, @item7_plusButtonPushed, true);
            app.item7_plus.Visible = 'off';
            app.item7_plus.Text = {'+'; ''};
            app.item7_minus = uibutton(app.LeftPanel, 'push');
            app.item7_minus.ButtonPushedFcn = createCallbackFcn(app, @item7_minusButtonPushed, true);
            app.item7_minus.Visible = 'off';
            app.item7_minus.Text = '-';
            app.item8_plus = uibutton(app.LeftPanel, 'push');
            app.item8_plus.ButtonPushedFcn = createCallbackFcn(app, @item8_plusButtonPushed, true);
            app.item8_plus.Visible = 'off';
            app.item8_plus.Text = {'+'; ''};
            app.item8_minus = uibutton(app.LeftPanel, 'push');
            app.item8_minus.ButtonPushedFcn = createCallbackFcn(app, @item8_minusButtonPushed, true);
            app.item8_minus.Visible = 'off';
            app.item8_minus.Text = '-';
            app.item9_plus = uibutton(app.LeftPanel, 'push');
            app.item9_plus.ButtonPushedFcn = createCallbackFcn(app, @item9_plusButtonPushed, true);
            app.item9_plus.Visible = 'off';
            app.item9_plus.Text = {'+'; ''};
            app.item9_minus = uibutton(app.LeftPanel, 'push');
            app.item9_minus.ButtonPushedFcn = createCallbackFcn(app, @item9_minusButtonPushed, true);
            app.item9_minus.Visible = 'off';
            app.item9_minus.Text = '-';
            app.item10_plus = uibutton(app.LeftPanel, 'push');
            app.item10_plus.ButtonPushedFcn = createCallbackFcn(app, @item10_plusButtonPushed, true);
            app.item10_plus.Visible = 'off';
            app.item10_plus.Text = {'+'; ''};
            app.item10_minus = uibutton(app.LeftPanel, 'push');
            app.item10_minus.ButtonPushedFcn = createCallbackFcn(app, @item10_minusButtonPushed, true);
            app.item10_minus.Visible = 'off';
            app.item10_minus.Text = '-';
            app.Syn = uibutton(app.LeftPanel, 'push');
            app.Syn.ButtonPushedFcn = createCallbackFcn(app, @SynButtonPushed, true);
            app.Syn.Text = '同步商品信息';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.CenterPanel);
            app.Image.Visible = 'off';
            app.unitPriceLabel = uilabel(app.CenterPanel);
            app.unitPriceLabel.Visible = 'off';
            app.unitPriceLabel.Text = '单价：';
            app.unitPrice = uieditfield(app.CenterPanel, 'numeric');
            app.unitPrice.Limits = [0 Inf];
            app.unitPrice.Editable = 'off';
            app.unitPrice.Visible = 'off';
            app.unitPrice.Value = 1;
            app.PaySuccess = uibutton(app.CenterPanel, 'push');
            app.PaySuccess.ButtonPushedFcn = createCallbackFcn(app, @PaySuccessButtonPushed, true);
            app.PaySuccess.Visible = 'off';
            app.PaySuccess.Text = '支付成功！点击继续下次购物';
            app.unitNumLabel = uilabel(app.CenterPanel);
            app.unitNumLabel.Visible = 'off';
            app.unitNumLabel.Text = '商品剩余数量：';
            app.unitNum = uieditfield(app.CenterPanel, 'numeric');
            app.unitNum.Limits = [0 Inf];
            app.unitNum.Editable = 'off';
            app.unitNum.Visible = 'off';
            app.unitNum.Value = 1;
            app.Printer = uibutton(app.CenterPanel, 'push');
            app.Printer.ButtonPushedFcn = createCallbackFcn(app, @PrinterButtonPushed, true);
            app.Printer.Visible = 'off';
            app.Printer.Text = '打印凭条';
            app.UITable_2 = uitable(app.CenterPanel);
            app.UITable_2.ColumnName = {'商品'; '数量'; '价钱'};
            app.UITable_2.RowName = {''};
            app.UITable_2.ColumnSortable = false;
            app.UITable_2.ColumnEditable = true;
            app.UITable_2.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'商品'; '数量'; '价钱'};
            app.UITable.RowName = {''};
            app.UITable.ColumnSortable = false;
            app.UITable.ColumnEditable = true;
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '总价：';
            app.TotalPrice = uieditfield(app.RightPanel, 'numeric');
            app.TotalPrice.Limits = [0 Inf];
            app.TotalPrice.Editable = 'off';
            app.PayButton = uibutton(app.RightPanel, 'push');
            app.PayButton.ButtonPushedFcn = createCallbackFcn(app, @PayButtonPushed, true);
            app.PayButton.Text = '支付';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CustomerUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end