classdef PlayerUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        LordCardPanel          matlab.ui.container.Panel
        LordCard_1             matlab.ui.control.TextArea
        LordCard_2             matlab.ui.control.TextArea
        LordCard_3             matlab.ui.control.TextArea
        LandlordCardLabel      matlab.ui.control.Label
        OutCardPanel           matlab.ui.container.Panel
        OutCard_1              matlab.ui.control.TextArea
        OutCard_2              matlab.ui.control.TextArea
        OutCard_3              matlab.ui.control.TextArea
        OutCard_4              matlab.ui.control.TextArea
        OutCard_5              matlab.ui.control.TextArea
        OutCard_6              matlab.ui.control.TextArea
        OutCard_7              matlab.ui.control.TextArea
        OutCard_8              matlab.ui.control.TextArea
        Player2_Panel          matlab.ui.container.Panel
        MessagePanel_2         matlab.ui.container.Panel
        Message_2              matlab.ui.control.Label
        CardsLeftPanel_2       matlab.ui.container.Panel
        CardsLeft2             matlab.ui.control.Label
        TurnLamp_2Label        matlab.ui.control.Label
        TurnLamp_2             matlab.ui.control.Lamp
        LandLordLamp_4Label    matlab.ui.control.Label
        LandLordLamp_2         matlab.ui.control.Lamp
        PlayerNameText_2       matlab.ui.control.Label
        PlayerScoreText_2      matlab.ui.control.Label
        Player1_Panel          matlab.ui.container.Panel
        MessagePanel           matlab.ui.container.Panel
        Message                matlab.ui.control.Label
        HandCardPanel          matlab.ui.container.Panel
        Button_1               matlab.ui.control.StateButton
        Button_2               matlab.ui.control.StateButton
        Button_3               matlab.ui.control.StateButton
        Button_4               matlab.ui.control.StateButton
        Button_5               matlab.ui.control.StateButton
        Button_6               matlab.ui.control.StateButton
        Button_7               matlab.ui.control.StateButton
        Button_8               matlab.ui.control.StateButton
        Button_9               matlab.ui.control.StateButton
        Button_10              matlab.ui.control.StateButton
        Button_11              matlab.ui.control.StateButton
        Button_12              matlab.ui.control.StateButton
        Button_13              matlab.ui.control.StateButton
        Button_14              matlab.ui.control.StateButton
        Button_15              matlab.ui.control.StateButton
        Button_16              matlab.ui.control.StateButton
        Button_17              matlab.ui.control.StateButton
        Button_18              matlab.ui.control.StateButton
        Button_19              matlab.ui.control.StateButton
        Button_20              matlab.ui.control.StateButton
        TurnLabel              matlab.ui.control.Label
        TurnLamp               matlab.ui.control.Lamp
        LandLordLamp_3Label    matlab.ui.control.Label
        LandLordLamp           matlab.ui.control.Lamp
        PlayCardPanel          matlab.ui.container.Panel
        PlayButton             matlab.ui.control.Button
        PassButton             matlab.ui.control.Button
        GrabLandLordPanel      matlab.ui.container.Panel
        GrabtheLandlordButton  matlab.ui.control.Button
        NotGrabButton          matlab.ui.control.Button
        PlayerNameText         matlab.ui.control.Label
        PlayerScoreText        matlab.ui.control.Label
        Player3_Panel          matlab.ui.container.Panel
        MessagePanel_3         matlab.ui.container.Panel
        Message_3              matlab.ui.control.Label
        CardsLeftPanel_3       matlab.ui.container.Panel
        CardsLeft3             matlab.ui.control.Label
        TurnLamp_3Label        matlab.ui.control.Label
        TurnLamp_3             matlab.ui.control.Lamp
        LandLordLamp_3Label_2  matlab.ui.control.Label
        LandLordLamp_3         matlab.ui.control.Lamp
        PlayerNameText_3       matlab.ui.control.Label
        PlayerScoreText_3      matlab.ui.control.Label
        StartGamePanel         matlab.ui.container.Panel
        StartGameButton        matlab.ui.control.Button
        WelcomeLabel           matlab.ui.control.Label
        StartScoreLabel        matlab.ui.control.Label
        StartScoreText         matlab.ui.control.Label
        StartNameText          matlab.ui.control.Label
        EnterNamePanel         matlab.ui.container.Panel
        TitleLabel             matlab.ui.control.Label
        NameEditFieldLabel     matlab.ui.control.Label
        EnterNameEditField     matlab.ui.control.EditField
        SubtitleLabel          matlab.ui.control.Label
        ConfirmButton          matlab.ui.control.Button
        EnterNameMessage       matlab.ui.control.Label
        ResultPanel            matlab.ui.container.Panel
        StartNextGameButton    matlab.ui.control.Button
        PlayerResult           matlab.ui.control.TextArea
        WinOrLoseLabel         matlab.ui.control.Label
        ScoreAdjust            matlab.ui.control.Label
        ScoreAdjust_2          matlab.ui.control.Label
        ScoreAdjust_3          matlab.ui.control.Label
        PlayerResult_2         matlab.ui.control.TextArea
        PlayerResult_3         matlab.ui.control.TextArea
        nameLabel              matlab.ui.control.Label
        nameLabel_2            matlab.ui.control.Label
        nameLabel_3            matlab.ui.control.Label
    end


    properties (Access = public)
        playerNum; 
        playerName;
        playerScore;
        
        
        startFlag = 0; 
        GameManager; 
        grabFlag = 0; % Init 0, grab 1, not grab -1
        playCardFlag = 0; 
        
        %outCardsIndex;
        
    end
    
    methods (Access = public)
        
        function displayMessage(app,Message)
            app.Message.Text=Message;
        end
        
        function app = displayHandCard(app,cards)
            app.HandCardPanel.Visible = 'on';
            if length(cards) < 20
              app.Button_18.Visible = 'off';
              app.Button_19.Visible = 'off';
              app.Button_20.Visible = 'off';
            end
            for i = 1:length(cards)
                button = eval(strcat('app.Button_',num2str(i)));
                button.Visible = 'on';
                button.Value = 0;
                
                cardName = [];
                
                cardsNum = cards(1,:);
                cardsType = cards(2,:);
                
                switch cardsNum(i)
                    case 11
                        cardName = {'J'};
                    case 12
                        cardName = {'Q'};
                    case 13
                        cardName = {'K'};
                    case 14
                        cardName = {'A'};
                    case 15 
                        cardName = {'2'};
                    case 16
                        cardName = [{'J'},{'O'},{'K'},{'E'},{'R'}];
                    case 17 
                        cardName = [{'J'},{'O'},{'K'},{'E'},{'R'}];
                    otherwise
                        cardName = {num2str(cards(1,i))};
                end
                switch cardsType(i)
                    case 1
                        cardName = [cardName,{'♥'}];
                    case 2
                        cardName = [cardName,{'♠'}];
                    case 3
                        cardName = [cardName,{'♣'}];
                    case 4 
                        cardName = [cardName,{'♦'}];        
                end
                button.Text = cardName;
                if cardsType(i) == 1 || cardsType(i) == 4 || cardsType(i) == 6
                    button.FontColor = [1.00,0.00,0.00];
                else
                    botton.FontColor = [0.00,0.00,0.00];
                end
            end
        end
        
        
        function LampOn(app,Lamp)
            Lamp.Color = [0.00,1.00,0.00];
        end
        
        function LampOff(app,Lamp)
            Lamp.Color = [0.65,0.65,0.65];
        end
        
        function displayLordCard(app,cards)
             app.LordCardPanel.Visible = 'on';
             for i = 1:length(cards)
                text = eval(strcat('app.LordCard_',num2str(i)));  
                cardName = [];
                
                cardsNum = cards(1,:);
                cardsType = cards(2,:);
                
                switch cardsNum(i)
                    case 11
                        cardName = {'J'};
                    case 12
                        cardName = {'Q'};
                    case 13
                        cardName = {'K'};
                    case 14
                        cardName = {'A'};
                    case 15 
                        cardName = {'2'};
                    case 16
                        cardName = [{'J'},{'O'},{'K'},{'E'},{'R'}];
                    case 17 
                        cardName = [{'J'},{'O'},{'K'},{'E'},{'R'}];
                    otherwise
                        cardName = {num2str(cards(1,i))};
                end
                switch cardsType(i)
                    case 1
                        cardName = [cardName,{'♥'}];
                    case 2
                        cardName = [cardName,{'♠'}];
                    case 3
                        cardName = [cardName,{'♣'}];
                    case 4 
                        cardName = [cardName,{'♦'}];        
                end
                text.Value = cardName;
                if cardsType(i) == 1 || cardsType(i) == 4 || cardsType(i) == 6
                     text.FontColor = [1.00,0.00,0.00];
                 else
                     text.FontColor = [0.00,0.00,0.00];
                end
            end
        end
        
        function displayOutCard(app,cards) 
             app.OutCardPanel.Visible = 'on';
             for i = 1:8
                 text = eval(strcat('app.OutCard_',num2str(i)));  
                 if i > length(cards(1,:))
                    text.Visible = 'off';
                    continue
                 end

                 cardsNum = cards(1,:);
                 cardsType = cards(2,:);
                 
                 cardName = [];
                 switch cardsNum(i)
                     case 11
                         cardName = {'J'};
                     case 12
                         cardName = {'Q'};
                     case 13
                         cardName = {'K'};
                     case 14
                         cardName = {'A'};
                     case 15 
                         cardName = {'2'};
                     case 16
                         cardName = [{'J'},{'O'},{'K'},{'E'},{'R'}];
                     case 17 
                         cardName = [{'J'},{'O'},{'K'},{'E'},{'R'}];
                     otherwise
                         cardName = {num2str(cards(1,i))};
                 end
                 switch cardsType(i)
                     case 1
                         cardName = [cardName,{'♥'}];
                     case 2
                         cardName = [cardName,{'♠'}];
                     case 3
                         cardName = [cardName,{'♣'}];
                     case 4 
                         cardName = [cardName,{'♦'}];        
                 end
                 text.Visible = 'on';
                 text.Value = cardName;
                 if cardsType(i) == 1 || cardsType(i) == 4 || cardsType(i) == 6
                     text.FontColor = [1.00,0.00,0.00];
                 else
                     text.FontColor = [0.00,0.00,0.00];
                 end
            end
        end
        

        function hideHandCard(app,cardsIndex)
            for i = 1:length(cardsIndex)
                button = eval(strcat('app.Button_',num2str(cardsIndex(i))));
                button.Visible = 'off'; 
            end
        end
        
        function displayLeftCard(app,player2Left,player3Left)
            app.CardsLeft2.Text = num2str(player2Left);
            app.CardsLeft3.Text = num2str(player3Left);
        end
        
        function displayResult(app,scoreList,adjustment)
            app.ResultPanel.Visible = 'on';
            app.startFlag = 0;
            if adjustment(app.playerNum) > 0
                app.WinOrLoseLabel.Text = 'You Win';
            else
                app.WinOrLoseLabel.Text = 'You Lose';
            end
            app.nameLabel.Text = app.PlayerNameText.Text;
            app.nameLabel_2.Text = app.PlayerNameText_2.Text;
            app.nameLabel_3.Text = app.PlayerNameText_3.Text;
            
            switch app.playerNum
                case 1
                   app.displayAdjust(app.ScoreAdjust,adjustment(1));
                   app.displayAdjust(app.ScoreAdjust_2,adjustment(2));
                   app.displayAdjust(app.ScoreAdjust_3,adjustment(3));
                case 2
                   app.displayAdjust(app.ScoreAdjust,adjustment(2));
                   app.displayAdjust(app.ScoreAdjust_2,adjustment(3));
                   app.displayAdjust(app.ScoreAdjust_3,adjustment(1));
                case 3
                   app.displayAdjust(app.ScoreAdjust,adjustment(3));
                   app.displayAdjust(app.ScoreAdjust_2,adjustment(1));
                   app.displayAdjust(app.ScoreAdjust_3,adjustment(2));
            end
            
            switch app.playerNum
                case 1
                   app.PlayerResult.Value = num2str(scoreList(1));
                   app.PlayerResult_2.Value = num2str(scoreList(2));
                   app.PlayerResult_3.Value = num2str(scoreList(3));
                case 2
                   app.PlayerResult.Value = num2str(scoreList(2));
                   app.PlayerResult_2.Value = num2str(scoreList(3));
                   app.PlayerResult_3.Value = num2str(scoreList(1));
                case 3
                   app.PlayerResult.Value = num2str(scoreList(3));
                   app.PlayerResult_2.Value = num2str(scoreList(1));
                   app.PlayerResult_3.Value = num2str(scoreList(2));
            end
            
            
        end
        
        
        function displayWaitPlayerScreen(app)
            app.cleanAllMessage();
            app.CardsLeft2.Text = '';
            app.CardsLeft3.Text = '';
            app.LampOff(app.TurnLamp);
            app.LampOff(app.TurnLamp_2);
            app.LampOff(app.TurnLamp_3);
            app.LampOff(app.LandLordLamp);
            app.LampOff(app.LandLordLamp_2);
            app.LampOff(app.LandLordLamp_3);
            app.OutCardPanel.Visible = 'off';
            app.HandCardPanel.Visible = 'off';
            app.LordCardPanel.Visible = 'off';
        end
        
        function cleanAllMessage(app)
            app.Message.Text = '';
            app.Message_2.Text = '';
            app.Message_3.Text = '';
        end
        
        function displayPlayerName(app,playerName2,playerName3)
            app.PlayerNameText.Text = app.playerName;
            app.PlayerNameText_2.Text = playerName2;
            app.PlayerNameText_3.Text = playerName3;
        end
        
        function displayPlayerScore(app,score1,score2,score3)
            app.PlayerScoreText.Text = ['Score: ',num2str(score1)];
            app.PlayerScoreText_2.Text = ['Score: ',num2str(score2)];
            app.PlayerScoreText_3.Text = ['Score: ',num2str(score3)];
        end
    end
    methods (Access = private)
        function displayAdjust(~,TextLabel,adjustNum)
            if adjustNum > 0
                TextLabel.Text = ['+',num2str(adjustNum)];
                TextLabel.FontColor = [1.00,0.00,0.00];
            else 
                TextLabel.Text = ['-',num2str(adjustNum)];
                TextLabel.FontColor = [0.00,1.00,0.00];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, playerNum)
            app.playerNum = playerNum; 
        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            app.StartGamePanel.Visible = 'off';
            app.Player1_Panel.Visible = 'on';
            app.Player2_Panel.Visible = 'on';
            app.Player3_Panel.Visible = 'on';
            app.GameManager.waitAllPlayerReady(app.playerNum);
%             app.displayHandCard(app.GameManager.CardManager.player1_cards);

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            %app.playCardFlag = 1;    
            outCardsIndex = [];
            playFlag = 1;
            for i = 1:20
                button = eval(strcat('app.Button_',num2str(i)));
                if strcmp(button.Visible,'on') && button.Value == 1
                    outCardsIndex =[outCardsIndex,i]; 
                end
            end
            app.GameManager.processPlayCard(app.playerNum,playFlag,outCardsIndex);
            
        end

        % Callback function
        function ImageClicked(app, event)
            app.Image.Visible = 'off';
        end

        % Button pushed function: GrabtheLandlordButton
        function GrabtheLandlordButtonPushed(app, event)
            app.GameManager.processGrabLordInput(app.playerNum,1);
        end

        % Button pushed function: NotGrabButton
        function NotGrabButtonPushed(app, event)
            app.GameManager.processGrabLordInput(app.playerNum,-1);
        end

        % Button pushed function: PassButton
        function PassButtonPushed(app, event)
            playFlag = 0;
            app.GameManager.processPlayCard(app.playerNum,playFlag,[]);   
        end

        % Button pushed function: StartNextGameButton
        function StartNextGameButtonPushed(app, event)
            app.ResultPanel.Visible = 'off';
            app.displayWaitPlayerScreen();
            app.GameManager.waitAllPlayerReady(app.playerNum);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            name = app.EnterNameEditField.Value;
            if isstrprop(name,'alphanum')
                if app.GameManager.isNameLogged(name)
                    app.EnterNameMessage.Text = 'This name has already been logged in.';
                    return
                end
                app.playerScore = app.GameManager.retrieveScore(app.playerNum,name);
                app.playerName = name;
                app.StartScoreText.Text = num2str(app.playerScore);
                app.StartNameText.Text = name;
                app.EnterNamePanel.Visible = 'off';
                app.StartGamePanel.Visible = 'on';
            else
                app.EnterNameMessage.Text = 'Only letters and numbers are valid in the name';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.LordCardPanel = uipanel(app.Panel);
            app.LordCardPanel.Visible = 'off';
            app.LordCard_1 = uitextarea(app.LordCardPanel);
            app.LordCard_2 = uitextarea(app.LordCardPanel);
            app.LordCard_3 = uitextarea(app.LordCardPanel);
            app.LandlordCardLabel = uilabel(app.LordCardPanel);
            app.LandlordCardLabel.Text = 'Landlord Card';
            app.OutCardPanel = uipanel(app.Panel);
            app.OutCardPanel.Visible = 'off';
            app.OutCard_1 = uitextarea(app.OutCardPanel);
            app.OutCard_2 = uitextarea(app.OutCardPanel);
            app.OutCard_3 = uitextarea(app.OutCardPanel);
            app.OutCard_4 = uitextarea(app.OutCardPanel);
            app.OutCard_5 = uitextarea(app.OutCardPanel);
            app.OutCard_6 = uitextarea(app.OutCardPanel);
            app.OutCard_7 = uitextarea(app.OutCardPanel);
            app.OutCard_8 = uitextarea(app.OutCardPanel);
            app.Player2_Panel = uipanel(app.Panel);
            app.Player2_Panel.Visible = 'off';
            app.MessagePanel_2 = uipanel(app.Player2_Panel);
            app.MessagePanel_2.Title = 'Message';
            app.Message_2 = uilabel(app.MessagePanel_2);
            app.Message_2.Text = '';
            app.CardsLeftPanel_2 = uipanel(app.Player2_Panel);
            app.CardsLeftPanel_2.Title = 'Cards Left';
            app.CardsLeft2 = uilabel(app.CardsLeftPanel_2);
            app.CardsLeft2.Text = '';
            app.TurnLamp_2Label = uilabel(app.Player2_Panel);
            app.TurnLamp_2Label.Text = 'Turn';
            app.TurnLamp_2 = uilamp(app.Player2_Panel);
            app.LandLordLamp_4Label = uilabel(app.Player2_Panel);
            app.LandLordLamp_4Label.Text = 'LandLord';
            app.LandLordLamp_2 = uilamp(app.Player2_Panel);
            app.PlayerNameText_2 = uilabel(app.Player2_Panel);
            app.PlayerNameText_2.Text = 'Name';
            app.PlayerScoreText_2 = uilabel(app.Player2_Panel);
            app.PlayerScoreText_2.Text = 'Score: ';
            app.Player1_Panel = uipanel(app.Panel);
            app.Player1_Panel.Visible = 'off';
            app.MessagePanel = uipanel(app.Player1_Panel);
            app.MessagePanel.Title = 'Message';
            app.Message = uilabel(app.MessagePanel);
            app.Message.Text = '';
            app.HandCardPanel = uipanel(app.Player1_Panel);
            app.Button_1 = uibutton(app.HandCardPanel, 'state');
            app.Button_1.Visible = 'off';
            app.Button_1.Text = {'Button'; ''};
            app.Button_2 = uibutton(app.HandCardPanel, 'state');
            app.Button_2.Visible = 'off';
            app.Button_2.Text = 'Button';
            app.Button_3 = uibutton(app.HandCardPanel, 'state');
            app.Button_3.Visible = 'off';
            app.Button_3.Text = 'Button';
            app.Button_4 = uibutton(app.HandCardPanel, 'state');
            app.Button_4.Visible = 'off';
            app.Button_4.Text = 'Button';
            app.Button_5 = uibutton(app.HandCardPanel, 'state');
            app.Button_5.Visible = 'off';
            app.Button_5.Text = 'Button';
            app.Button_6 = uibutton(app.HandCardPanel, 'state');
            app.Button_6.Visible = 'off';
            app.Button_6.Text = 'Button';
            app.Button_7 = uibutton(app.HandCardPanel, 'state');
            app.Button_7.Visible = 'off';
            app.Button_7.Text = 'Button';
            app.Button_8 = uibutton(app.HandCardPanel, 'state');
            app.Button_8.Visible = 'off';
            app.Button_8.Text = 'Button';
            app.Button_9 = uibutton(app.HandCardPanel, 'state');
            app.Button_9.Visible = 'off';
            app.Button_9.Text = 'Button';
            app.Button_10 = uibutton(app.HandCardPanel, 'state');
            app.Button_10.Visible = 'off';
            app.Button_10.Text = 'Button';
            app.Button_11 = uibutton(app.HandCardPanel, 'state');
            app.Button_11.Visible = 'off';
            app.Button_11.Text = 'Button';
            app.Button_12 = uibutton(app.HandCardPanel, 'state');
            app.Button_12.Visible = 'off';
            app.Button_12.Text = 'Button';
            app.Button_13 = uibutton(app.HandCardPanel, 'state');
            app.Button_13.Visible = 'off';
            app.Button_13.Text = 'Button';
            app.Button_14 = uibutton(app.HandCardPanel, 'state');
            app.Button_14.Visible = 'off';
            app.Button_14.Text = 'Button';
            app.Button_15 = uibutton(app.HandCardPanel, 'state');
            app.Button_15.Visible = 'off';
            app.Button_15.Text = 'Button';
            app.Button_16 = uibutton(app.HandCardPanel, 'state');
            app.Button_16.Visible = 'off';
            app.Button_16.Text = 'Button';
            app.Button_17 = uibutton(app.HandCardPanel, 'state');
            app.Button_17.Visible = 'off';
            app.Button_17.Text = 'Button';
            app.Button_18 = uibutton(app.HandCardPanel, 'state');
            app.Button_18.Visible = 'off';
            app.Button_18.Text = 'Button';
            app.Button_19 = uibutton(app.HandCardPanel, 'state');
            app.Button_19.Visible = 'off';
            app.Button_19.Text = 'Button';
            app.Button_20 = uibutton(app.HandCardPanel, 'state');
            app.Button_20.Visible = 'off';
            app.Button_20.Text = 'Button';
            app.TurnLabel = uilabel(app.Player1_Panel);
            app.TurnLabel.Text = 'Turn';
            app.TurnLamp = uilamp(app.Player1_Panel);
            app.LandLordLamp_3Label = uilabel(app.Player1_Panel);
            app.LandLordLamp_3Label.Text = 'LandLord';
            app.LandLordLamp = uilamp(app.Player1_Panel);
            app.PlayCardPanel = uipanel(app.Player1_Panel);
            app.PlayCardPanel.Visible = 'off';
            app.PlayButton = uibutton(app.PlayCardPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PassButton = uibutton(app.PlayCardPanel, 'push');
            app.PassButton.ButtonPushedFcn = createCallbackFcn(app, @PassButtonPushed, true);
            app.PassButton.Text = 'Pass';
            app.GrabLandLordPanel = uipanel(app.Player1_Panel);
            app.GrabLandLordPanel.Visible = 'off';
            app.GrabtheLandlordButton = uibutton(app.GrabLandLordPanel, 'push');
            app.GrabtheLandlordButton.ButtonPushedFcn = createCallbackFcn(app, @GrabtheLandlordButtonPushed, true);
            app.GrabtheLandlordButton.Text = 'Grab the Landlord';
            app.NotGrabButton = uibutton(app.GrabLandLordPanel, 'push');
            app.NotGrabButton.ButtonPushedFcn = createCallbackFcn(app, @NotGrabButtonPushed, true);
            app.NotGrabButton.Text = 'Not Grab';
            app.PlayerNameText = uilabel(app.Player1_Panel);
            app.PlayerNameText.Text = 'Name';
            app.PlayerScoreText = uilabel(app.Player1_Panel);
            app.PlayerScoreText.Text = 'Score: ';
            app.Player3_Panel = uipanel(app.Panel);
            app.Player3_Panel.Visible = 'off';
            app.MessagePanel_3 = uipanel(app.Player3_Panel);
            app.MessagePanel_3.Title = 'Message';
            app.Message_3 = uilabel(app.MessagePanel_3);
            app.Message_3.Text = '';
            app.CardsLeftPanel_3 = uipanel(app.Player3_Panel);
            app.CardsLeftPanel_3.Title = 'Cards Left';
            app.CardsLeft3 = uilabel(app.CardsLeftPanel_3);
            app.CardsLeft3.Text = '';
            app.TurnLamp_3Label = uilabel(app.Player3_Panel);
            app.TurnLamp_3Label.Text = 'Turn';
            app.TurnLamp_3 = uilamp(app.Player3_Panel);
            app.LandLordLamp_3Label_2 = uilabel(app.Player3_Panel);
            app.LandLordLamp_3Label_2.Text = 'LandLord';
            app.LandLordLamp_3 = uilamp(app.Player3_Panel);
            app.PlayerNameText_3 = uilabel(app.Player3_Panel);
            app.PlayerNameText_3.Text = 'Name';
            app.PlayerScoreText_3 = uilabel(app.Player3_Panel);
            app.PlayerScoreText_3.Text = 'Score: ';
            app.StartGamePanel = uipanel(app.Panel);
            app.StartGamePanel.Visible = 'off';
            app.StartGameButton = uibutton(app.StartGamePanel, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.WelcomeLabel = uilabel(app.StartGamePanel);
            app.WelcomeLabel.Text = 'Welcome !';
            app.StartScoreLabel = uilabel(app.StartGamePanel);
            app.StartScoreLabel.Text = 'Your score :';
            app.StartScoreText = uilabel(app.StartGamePanel);
            app.StartScoreText.Text = '';
            app.StartNameText = uilabel(app.StartGamePanel);
            app.StartNameText.Text = '';
            app.EnterNamePanel = uipanel(app.Panel);
            app.TitleLabel = uilabel(app.EnterNamePanel);
            app.TitleLabel.Text = 'Please enter your name';
            app.NameEditFieldLabel = uilabel(app.EnterNamePanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.EnterNameEditField = uieditfield(app.EnterNamePanel, 'text');
            app.SubtitleLabel = uilabel(app.EnterNamePanel);
            app.SubtitleLabel.Text = {'                    If you are a new player,'; ' enter your name will be automatically registered'};
            app.ConfirmButton = uibutton(app.EnterNamePanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.EnterNameMessage = uilabel(app.EnterNamePanel);
            app.EnterNameMessage.Text = '';
            app.ResultPanel = uipanel(app.Panel);
            app.ResultPanel.Visible = 'off';
            app.StartNextGameButton = uibutton(app.ResultPanel, 'push');
            app.StartNextGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartNextGameButtonPushed, true);
            app.StartNextGameButton.Text = 'Start Next Game';
            app.PlayerResult = uitextarea(app.ResultPanel);
            app.WinOrLoseLabel = uilabel(app.ResultPanel);
            app.WinOrLoseLabel.Text = 'You Win';
            app.ScoreAdjust = uilabel(app.ResultPanel);
            app.ScoreAdjust.Text = '+3';
            app.ScoreAdjust_2 = uilabel(app.ResultPanel);
            app.ScoreAdjust_2.Text = '+3';
            app.ScoreAdjust_3 = uilabel(app.ResultPanel);
            app.ScoreAdjust_3.Text = '-6';
            app.PlayerResult_2 = uitextarea(app.ResultPanel);
            app.PlayerResult_3 = uitextarea(app.ResultPanel);
            app.nameLabel = uilabel(app.ResultPanel);
            app.nameLabel.Text = 'p1';
            app.nameLabel_2 = uilabel(app.ResultPanel);
            app.nameLabel_2.Text = {'p2'; ''};
            app.nameLabel_3 = uilabel(app.ResultPanel);
            app.nameLabel_3.Text = 'p3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlayerUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end